classdef Analog_In_New_Functions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ClearButton              matlab.ui.control.Button
        AnalogInputExampleLabel  matlab.ui.control.Label
        VoltageEditFieldLabel    matlab.ui.control.Label
        VoltageEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        AnalogIn % analog input
        dq % DAQ session
        dev % DAQ device
        VoltIn %input voltage
        ch1 %channel that voltage is read in
        data %matrix of voltage channels read in, each channel is a column in this matrix
        timestamps %when output format is matrix (see line 30), timestamps are read in as well as your data
    end
    
    methods (Access = private)
        
        function plotData(app, src, ~)
            % Plots input voltage over time
            [app.data, app.timestamps, ~] = read(src,src.ScansAvailableFcnCount,"OutputFormat","Matrix");
            app.VoltIn = app.data(:,1);
            plot(app.UIAxes, app.timestamps, app.VoltIn,'g'); %plot with green line
            app.VoltageEditField.Value=app.VoltIn(1); %display voltage in numeric field
            
            % hold axes so graph does not clear
            hold(app.UIAxes, 'on');
                      
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % disable unneccesary components
            app.StopButton.Enable = false;

            % set up NIDAQ
            app.dq = daq("ni");
            app.ch1 = addinput(app.dq,"Dev1","ai1","Voltage"); %check device id with analog input recorder under apps in main matlab window, here its "Dev1"
            app.dq.Rate=15; %sample rate 15 samples/sec
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % prevent double-clicks
            app.StartButton.Enable = false;
            
            %plot data continuously
            app.dq.ScansAvailableFcn = @(src,evt) plotData(app,src,evt);
            
            start(app.dq, "continuous");       


            % enable components
            app.StopButton.Enable = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % disable unneccesary components
            app.StopButton.Enable = false;
            
            % save input data
            t = datestr(now, 'mm-dd-yyyy_HHMMSS');
            string = strcat('SampleCodeVoltages_',t);
            xlswrite(string, app.VoltIn);
            
            % stop aquisition
                              
            stop(app.dq);
            removechannel(app.dq, [1:length(app.dq.Channels)]);
            %removeChannel(app.s,2);
            
            % enable component
            hold(app.UIAxes, 'off');
            app.StartButton.Enable = true;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % clear axes
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Voltage')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, {'Voltage (V)'; ''})
            app.UIAxes.YLim = [0 8];
            app.UIAxes.YTick = [0 2 4 6 8];
            app.UIAxes.YTickLabel = {'0'; '2'; '4'; '6'; '8'};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AnalogInputExampleLabel = uilabel(app.UIFigure);
            app.AnalogInputExampleLabel.Text = 'Analog Input Example';
            app.VoltageEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analog_In_New_Functions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end