classdef Lab08GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PressureChannelDropDown        matlab.ui.control.DropDown
        PressureChannelDropDownLabel   matlab.ui.control.Label
        TemperatureChannelDropDown     matlab.ui.control.DropDown
        TemperatureChannelDropDownLabel  matlab.ui.control.Label
        RegulatorChannelDropDown       matlab.ui.control.DropDown
        RegulatorChannelDropDownLabel  matlab.ui.control.Label
        SampleRatesampsEditField       matlab.ui.control.NumericEditField
        SampleRateLabel                matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        AltAxes                        matlab.ui.control.UIAxes
        PressureAxes                   matlab.ui.control.UIAxes
        TempAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn
        d
        dev
        data
        chv                 %variable for regulator channel 
        cht                 %variable for temp channel
        chp                 %variable for pressure channel
        ch0
        ch1
        ch2
        inTemp_v            %intput temperature in volts from DAQ
        inPres_v            %intput pressure in volts from DAQ
        Vreg                %monitor regulator voltage to make sure it is constant
        temp
        tempK
        pres
        alt
        p0 = 101.333;       %pressure at sea level [kPa]
        R = 8.31;           %
        g = 9.81;           %acceleration of gravity
        m = 0.02896;        %mean molecular mass of air
        AllDataTimeTable
        NewDataTimeTable
        timestamps
    end
    
    methods (Access = private)
        function plotData(app,scan,~)   %~ is neccessary if you are not using the third input event
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");
            app.inTemp_v = app.data(:,1);
            app.inPres_v = app.data(:,2);
            app.Vreg = app.data(:,3);
            app.temp = app.inTemp_v/0.01;
            app.tempK = app.temp+273;
            app.pres = ((app.inPres_v./app.Vreg) + 0.095)/0.009;
            app.alt = log(app.pres/app.p0).*((-app.R*app.tempK)/(app.m*app.g));
            %app.NewDataTimeTable = writetimetable(datetime("now"),mean(app.inTemp_v),mean(app.inPres_v),mean(app.Vreg),mean(app.temp),mean(app.pres),mean(app.alt),'VariableNames',{'Time','V_temp','V_pressure','V_regulator','Temperature [C]','Pressure [kPa]','Altitude [m]'});
            plot(app.TempAxes,app.timestamps,app.inTemp_v,"g");
            hold(app.TempAxes, "on");
            plot(app.PressureAxes,app.timestamps,app.inPres_v,"r");
            hold(app.PressureAxes, "on");
            plot(app.AltAxes,app.timestamps,app.alt,"b");
            hold(app.AltAxes, "on");
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
            devices = daqlist("ni");                                                                    %creats list of devides
            app.dev = devices(1,"DeviceID");                                                            %saves device name of first daq found in app.dev
            app.d = daq('ni');                                                                          %create data aquisition object, always need this line*** passes to all function that do data aquisition
            app.chv = app.RegulatorChannelDropDown.Value;
            app.cht = app.TemperatureChannelDropDown.Value;
            app.chp = app.PressureChannelDropDown.Value;
%             app.ch0 = addinput(app.d, app.dev.DeviceID,app.TemperatureChannelDropDown,'voltage');       %Doesn't recomend hard coding the output channel, app.*channelname*.Value attatched to drop down list 
%             app.ch1 = addinput(app.d, app.dev.DeviceID,app.PressureChannelDropDown,'voltage');
%             app.ch2 = addinput(app.d, app.dev.DeviceID,app.RegulatorChannelDropDown,'voltage');
%             app.ch0.TerminalConfig='Differential';
%             app.ch1.TerminalConfig='Differential';
%             app.ch2.TerminalConfig='Differential';
            app.d.Rate = 10;                                                                      
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TemperatureChannelDropDown.Enable = 'off';
            app.PressureChannelDropDown.Enable = 'off';
            app.RegulatorChannelDropDown.Enable = "off";                        %cant change channels during measurement
            app.StartButton.Enable = "off";                                     %Prevent user from double clicking start
            app.StopButton.Enable = "on";

            app.ch0 = addinput(app.d, app.dev.DeviceID,app.cht,'voltage');       %Doesn't recomend hard coding the output channel, app.*channelname*.Value attatched to drop down list 
            app.ch1 = addinput(app.d, app.dev.DeviceID,app.chp,'voltage');
            app.ch2 = addinput(app.d, app.dev.DeviceID,app.chv,'voltage');
            app.ch0.TerminalConfig='Differential';
            app.ch1.TerminalConfig='Differential';
            app.ch2.TerminalConfig='Differential';

            cla(app.TempAxes);                                                  %Clear plots for consecutive measurements
            cla(app.PressureAxes);
            cla(app.AltAxes);
 %           app.ScansAvailibleFcnCount = app.d.Rate*1;                         %only aquires every x number of samples
            app.d.ScansAvailableFcn = @(scan,evt) plotData(app,scan);           %Executes once callback is met
            start(app.d,"continuous");

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % disable unneccesary components
            app.StopButton.Enable = false;
            

            % stop aquisition
                              
            stop(app.d);
            removechannel(app.d, 1:length(app.d.Channels));
            %removeChannel(app.s,2);

            % save input data
            writetable(app.NewDataTimeTable,"Lab08data.xls")
            % enable component
            hold(app.TempAxes, 'off');
            app.StartButton.Enable = true;
        end

        % Value changed function: SampleRatesampsEditField
        function SampleRateChng(app, event)
            app.d.rate = app.SampleRatesampsEditField.Value; 
        end

        % Value changed function: TemperatureChannelDropDown
        function tempchannelchange(app, event)
            app.cht = app.TemperatureChannelDropDown.Value;
        end

        % Value changed function: PressureChannelDropDown
        function preschangeevent(app, event)
            app.chp = app.PressureChannelDropDown.Value;
        end

        % Value changed function: RegulatorChannelDropDown
        function regchangeevent(app, event)
            app.chv = app.RegulatorChannelDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Measured Temperature')
            xlabel(app.TempAxes, 'Time [s]')
            ylabel(app.TempAxes, 'Temperature [C]')
            zlabel(app.TempAxes, 'Z')
            app.PressureAxes = uiaxes(app.UIFigure);
            title(app.PressureAxes, 'Measured Pressure')
            xlabel(app.PressureAxes, 'Time [s]')
            ylabel(app.PressureAxes, 'Pressure [kPa]')
            zlabel(app.PressureAxes, 'Z')
            app.AltAxes = uiaxes(app.UIFigure);
            title(app.AltAxes, 'Calculated Altitude')
            xlabel(app.AltAxes, 'Time [s]')
            ylabel(app.AltAxes, 'Altitude [m]')
            zlabel(app.AltAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SampleRateLabel = uilabel(app.UIFigure);
            app.SampleRateLabel.Text = '   Sample Rate [samp/s]';
            app.SampleRatesampsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleRatesampsEditField.Limits = [1 10000];
            app.SampleRatesampsEditField.RoundFractionalValues = 'on';
            app.SampleRatesampsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateChng, true);
            app.SampleRatesampsEditField.Value = 10;
            app.RegulatorChannelDropDownLabel = uilabel(app.UIFigure);
            app.RegulatorChannelDropDownLabel.Text = 'Regulator Channel';
            app.RegulatorChannelDropDown = uidropdown(app.UIFigure);
            app.RegulatorChannelDropDown.Items = {'ai0', 'ai2', 'ai1', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.RegulatorChannelDropDown.Editable = 'on';
            app.RegulatorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @regchangeevent, true);
            app.RegulatorChannelDropDown.Placeholder = 'Select Channel';
            app.RegulatorChannelDropDown.Value = 'ai2';
            app.TemperatureChannelDropDownLabel = uilabel(app.UIFigure);
            app.TemperatureChannelDropDownLabel.Text = 'Temperature Channel';
            app.TemperatureChannelDropDown = uidropdown(app.UIFigure);
            app.TemperatureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.TemperatureChannelDropDown.Editable = 'on';
            app.TemperatureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @tempchannelchange, true);
            app.TemperatureChannelDropDown.Placeholder = 'Select Channel';
            app.TemperatureChannelDropDown.Value = 'ai0';
            app.PressureChannelDropDownLabel = uilabel(app.UIFigure);
            app.PressureChannelDropDownLabel.Text = 'Pressure Channel';
            app.PressureChannelDropDown = uidropdown(app.UIFigure);
            app.PressureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PressureChannelDropDown.Editable = 'on';
            app.PressureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @preschangeevent, true);
            app.PressureChannelDropDown.Placeholder = 'Select Channel';
            app.PressureChannelDropDown.Value = 'ai1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab08GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end