classdef Lab09GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataPlotsPanel                 matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        TDAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        TempAxes                       matlab.ui.control.UIAxes
        RHAxes                         matlab.ui.control.UIAxes
        AltAxes                        matlab.ui.control.UIAxes
        PressureAxes                   matlab.ui.control.UIAxes
        UserParametersPanel            matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        RegulatorChannelDropDown       matlab.ui.control.DropDown
        RegulatorChannelDropDownLabel  matlab.ui.control.Label
        TemperatureChannelDropDown     matlab.ui.control.DropDown
        TemperatureChannelDropDownLabel  matlab.ui.control.Label
        PressureChannelDropDown        matlab.ui.control.DropDown
        PressureChannelDropDownLabel   matlab.ui.control.Label
        RHChannelDropDown              matlab.ui.control.DropDown
        RHChannelDropDownLabel         matlab.ui.control.Label
        SampleRateHzEditField          matlab.ui.control.NumericEditField
        SampleRateLabel                matlab.ui.control.Label
        UserButtonsPanel               matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        StartButton                    matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        AnalogIn
        d                   %variable for data aquisition object
        dev                 %variable to be set equal to DeviceID
        data
        chv                 %variable for regulator channel 
        cht                 %variable for temp channel
        chp                 %variable for pressure channel
        chrh                %variable for rh channel
        chv_default         %default channel value
        chp_default         %default channel value
        cht_default         %default channel value
        chrh_default        %default channel value
        ch0
        ch1
        ch2
        ch3
        channelFlag
        inTemp_v            %intput temperature in volts from DAQ
        inPres_v            %intput pressure in volts from DAQ
        Vreg                %monitor regulator voltage to make sure it is constant
        RH_v                %Relative humidity sensor voltage
        RH                  %Relative humidity value
        T_d                 %Dew point temperature
        temp
        tempK
        pres
        alt
        p0 = 101.333;       %pressure at sea level [kPa]
        R = 8.31;           %
        g = 9.81;           %acceleration of gravity
        m = 0.02896;        %mean molecular mass of air
        VariableNames = {'Temperature Voltage','Pressure Voltage','Regulator Voltage','RH Voltage','Temperature','Pressure','RH','Altitude','Dew Point'};
        VariableUnits = {'V','V','V','V','C','kPa','%','m','C'};
        AllDataTimeTable
        NewDataTimeTable
        timestamps
    end
    
    methods (Access = private)
        function plotData(app,scan,~)   %~ is neccessary if you are not using the third input event
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");
            app.inTemp_v = app.data(:,1);
            app.RH_v = app.data(:,2);
            app.inPres_v = app.data(:,3);
            app.Vreg = app.data(:,4);
            app.RH = RH_func(app,app.RH_v);
            app.temp = temp_func(app,app.inTemp_v);
            app.tempK = CtoK(app,app.temp);
            app.T_d = dewpt_func(app,app.temp,app.RH);
            app.pres = pres_func(app,app.inPres_v,app.Vreg);
            app.alt = Alt_func(app,app.pres,app.tempK);

            app.NewDataTimeTable = timetable(datetime('now'),mean(app.inTemp_v),mean(app.inPres_v),mean(app.Vreg), ...
                                                            mean(app.RH_v),mean(app.temp),mean(app.pres),mean(app.RH), ...
                                                            mean(app.alt),mean(app.T_d));
            app.NewDataTimeTable.Properties.VariableNames=app.AllDataTimeTable.Properties.VariableNames;
            app.AllDataTimeTable=vertcat(app.AllDataTimeTable,app.NewDataTimeTable);                        %concatinate below the previous row

            plot(app.TempAxes,app.timestamps,app.temp,"g");                %Plot data
            plot(app.PressureAxes,app.timestamps,app.pres,"r");
            plot(app.AltAxes,app.timestamps,app.alt,"b");
            plot(app.RHAxes,app.timestamps,app.RH,"r");
            plot (app.TDAxes,app.timestamps,app.T_d,"r");
            hold(app.PressureAxes, "on");                                  %keep plot between scans
            hold(app.TempAxes, "on");
            hold(app.AltAxes, "on");
            hold(app.RHAxes,"on");
            hold(app.TDAxes,"on");
        end
        
        %Function determines if channels are overlapping and resets to
        %default if unavailible
        function ValidChannel = channel_Logic(app,channel_name,channel_val,channel_default,channel_ddv)
            app.channelFlag = 0;            %Flag to determine if invalid value was used, runs default chaeck if set
            if string(channel_ddv) == string(app.cht) && channel_name ~= "app.cht"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            elseif string(channel_ddv) == string(app.chv) && channel_name ~= "app.chv"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            elseif string(channel_ddv) == string(app.chp) && channel_name ~= "app.chp"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            elseif string(channel_ddv) == string(app.chrh) && channel_name ~= "app.chrh"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            else 
                ValidChannel = channel_ddv;
            end
            if app.channelFlag == 1
                if app.cht == channel_default
                    app.TemperatureChannelDropDown.Value = app.cht_default;
                elseif app.chp == channel_default
                    app.PressureChannelDropDown.Value = app.chp_default;
                elseif app.chv == channel_default
                    app.RegulatorChannelDropDown.Value = app.chv_default;
                elseif app.chrh == channel_default
                    app.RHChannelDropDown.Value = app.chrh_default;
                end
            end
        end
        
        function results = RH_func(app,RH_v)
            results = (RH_v-0.825)/0.03;
        end
        
        function results = temp_func(app,t_v)
            results = t_v/0.01;
        end
        
        function results = CtoK(app,t_c)
            results = t_c+273;
        end
        
        function results = dewpt_func(app,temp,RH)
            results = temp - ((100-RH)/5);
        end
        
        function results = pres_func(app,P_v,V_reg)
            results = ((P_v./V_reg) + 0.095)/0.009;
        end
        
        function results = Alt_func(app,P,t_K)
            results = log(P/app.p0).*((-app.R*t_K)/(app.m*app.g));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
            devices = daqlist("ni");                                                                    %creats list of devides
            app.dev = devices(1,"DeviceID");                                                            %saves device name of first daq found in app.dev
            app.d = daq('ni');                                                                          %create data aquisition object, always need this line*** passes to all function that do data aquisition
            app.chv = app.RegulatorChannelDropDown.Value;
            app.cht = app.TemperatureChannelDropDown.Value;
            app.chp = app.PressureChannelDropDown.Value;
            app.chrh = app.RHChannelDropDown.Value;
            app.chv_default  = app.chv;       %default channel value
            app.chp_default  = app.chp;       %default channel value
            app.cht_default  = app.cht;       %default channel value
            app.chrh_default  = app.chrh;     %default channel value
            app.d.Rate = 10;                                                                      
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.ClearButton.Enable = false;
            app.TemperatureChannelDropDown.Enable = 'off';
            app.PressureChannelDropDown.Enable = 'off';
            app.RegulatorChannelDropDown.Enable = "off";                        %cant change channels during measurement
            app.RHChannelDropDown.Enable = "off";  
            app.SampleRateHzEditField.Enable="off";                             %disable sample rate selector
            app.StartButton.Enable = "off";                                     %Prevent user from double clicking start
            app.StopButton.Enable = "on";

            app.ch0 = addinput(app.d, app.dev.DeviceID,app.cht,'voltage');
            app.ch1 = addinput(app.d, app.dev.DeviceID,app.chrh,'voltage');
            app.ch2 = addinput(app.d, app.dev.DeviceID,app.chp,'voltage');
            app.ch3 = addinput(app.d, app.dev.DeviceID,app.chv,'voltage');
            app.ch0.TerminalConfig='Differential';
            app.ch1.TerminalConfig='Differential';
            app.ch2.TerminalConfig='Differential';
            app.ch3.TerminalConfig='Differential';
            app.AllDataTimeTable = timetable(NaT(0,1),[],[],[],[],[],[],[],[],[],'VariableNames',app.VariableNames);         %reserve space for all variables to be written to table as awell as space holder for time
            app.AllDataTimeTable.Properties.VariableUnits={'V','V','V','V','C','kPa','m','%','C'};

            cla(app.TempAxes);                                                      %Clear plots for consecutive measurements
            cla(app.PressureAxes);
            cla(app.AltAxes);
            cla(app.TDAxes);
            cla(app.RHAxes);
            
            app.d.ScansAvailableFcnCount = app.d.Rate*1;                            %only aquires every x number of samples
            app.d.ScansAvailableFcn = @(scan,evt) plotData(app,scan,evt);           %Executes once callback is met
            start(app.d,"continuous");

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % disable unneccesary components
            app.StopButton.Enable = false;
            
            writetimetable(app.AllDataTimeTable,"Lab09data.csv");

            stop(app.d);
            removechannel(app.d, 1:length(app.d.Channels));

            % re-enable components
            hold(app.TempAxes, 'off');
            app.TemperatureChannelDropDown.Enable = 'on';
            app.PressureChannelDropDown.Enable = 'on';
            app.RegulatorChannelDropDown.Enable = "on";   
            app.RHChannelDropDown.Enable = "on";                   
            app.SampleRateHzEditField.Enable="on";                            
            app.StartButton.Enable = "on";                                  
            app.ClearButton.Enable = true;
        end

        % Value changed function: SampleRateHzEditField
        function SampleRateChng(app, event)
            app.d.rate = app.SampleRateHzEditField.Value; 
        end

        % Value changed function: TemperatureChannelDropDown
        function tempchannelchange(app, event)
            app.TemperatureChannelDropDown.Value = channel_Logic(app,"cht",app.cht,app.cht_default,app.TemperatureChannelDropDown.Value);
            app.cht = app.TemperatureChannelDropDown.Value;
        end

        % Value changed function: PressureChannelDropDown
        function preschangeevent(app, event)
            app.PressureChannelDropDown.Value = channel_Logic(app,"chp",app.chp,app.chp_default,app.PressureChannelDropDown.Value);
            app.chp = app.PressureChannelDropDown.Value;
        end

        % Value changed function: RegulatorChannelDropDown
        function regchangeevent(app, event)
            app.RegulatorChannelDropDown.Value = channel_Logic(app,"chv",app.chv,app.chv_default,app.RegulatorChannelDropDown.Value);
            app.chv = app.RegulatorChannelDropDown.Value;
        end

        % Value changed function: RHChannelDropDown
        function RHChannelDropDownValueChanged(app, event)
            app.RHChannelDropDown.Value = channel_Logic(app,"chrh",app.chrh,app.chrh_default,app.RHChannelDropDown.Value);
            app.chrh = app.RHChannelDropDown.Value;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.TempAxes);                                                      %Clear plots for consecutive measurements
            cla(app.PressureAxes);
            cla(app.AltAxes);
            cla(app.TDAxes);
            cla(app.RHAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserButtonsPanel = uipanel(app.UIFigure);
            app.UserButtonsPanel.Title = 'User Buttons';
            app.GridLayout4 = uigridlayout(app.UserButtonsPanel);
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearButton = uibutton(app.GridLayout4, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StartButton = uibutton(app.GridLayout4, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UserParametersPanel = uipanel(app.UIFigure);
            app.UserParametersPanel.Title = 'User Parameters';
            app.GridLayout5 = uigridlayout(app.UserParametersPanel);
            app.SampleRateLabel = uilabel(app.GridLayout5);
            app.SampleRateLabel.Text = '   Sample Rate [Hz]';
            app.SampleRateHzEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampleRateHzEditField.Limits = [1 10000];
            app.SampleRateHzEditField.RoundFractionalValues = 'on';
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateChng, true);
            app.SampleRateHzEditField.Value = 10;
            app.RHChannelDropDownLabel = uilabel(app.GridLayout5);
            app.RHChannelDropDownLabel.Text = 'RH Channel';
            app.RHChannelDropDown = uidropdown(app.GridLayout5);
            app.RHChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.RHChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @RHChannelDropDownValueChanged, true);
            app.RHChannelDropDown.Value = 'ai1';
            app.PressureChannelDropDownLabel = uilabel(app.GridLayout5);
            app.PressureChannelDropDownLabel.Text = 'Pressure Channel';
            app.PressureChannelDropDown = uidropdown(app.GridLayout5);
            app.PressureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PressureChannelDropDown.Editable = 'on';
            app.PressureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @preschangeevent, true);
            app.PressureChannelDropDown.Placeholder = 'Select Channel';
            app.PressureChannelDropDown.Value = 'ai2';
            app.TemperatureChannelDropDownLabel = uilabel(app.GridLayout5);
            app.TemperatureChannelDropDownLabel.Text = 'Temperature Channel';
            app.TemperatureChannelDropDown = uidropdown(app.GridLayout5);
            app.TemperatureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.TemperatureChannelDropDown.Editable = 'on';
            app.TemperatureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @tempchannelchange, true);
            app.TemperatureChannelDropDown.Placeholder = 'Select Channel';
            app.TemperatureChannelDropDown.Value = 'ai0';
            app.RegulatorChannelDropDownLabel = uilabel(app.GridLayout5);
            app.RegulatorChannelDropDownLabel.Text = 'Regulator Channel';
            app.RegulatorChannelDropDown = uidropdown(app.GridLayout5);
            app.RegulatorChannelDropDown.Items = {'ai0', 'ai2', 'ai1', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.RegulatorChannelDropDown.Editable = 'on';
            app.RegulatorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @regchangeevent, true);
            app.RegulatorChannelDropDown.Placeholder = 'Select Channel';
            app.RegulatorChannelDropDown.Value = 'ai3';
            app.DataPlotsPanel = uipanel(app.UIFigure);
            app.DataPlotsPanel.Title = 'Data Plots';
            app.GridLayout = uigridlayout(app.DataPlotsPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PressureAxes = uiaxes(app.GridLayout2);
            title(app.PressureAxes, 'Measured Pressure')
            xlabel(app.PressureAxes, 'Time [s]')
            ylabel(app.PressureAxes, 'Pressure [kPa]')
            zlabel(app.PressureAxes, 'Z')
            app.AltAxes = uiaxes(app.GridLayout2);
            title(app.AltAxes, 'Calculated Altitude')
            xlabel(app.AltAxes, 'Time [s]')
            ylabel(app.AltAxes, 'Altitude [m]')
            zlabel(app.AltAxes, 'Z')
            app.RHAxes = uiaxes(app.GridLayout2);
            title(app.RHAxes, 'Measured Relative Humidity %')
            xlabel(app.RHAxes, 'Time [s]')
            ylabel(app.RHAxes, 'RH [%]')
            zlabel(app.RHAxes, 'Z')
            app.TempAxes = uiaxes(app.GridLayout2);
            title(app.TempAxes, 'Measured Temperature')
            xlabel(app.TempAxes, 'Time [s]')
            ylabel(app.TempAxes, 'Temperature [C]')
            zlabel(app.TempAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TDAxes = uiaxes(app.GridLayout3);
            title(app.TDAxes, 'Calculated Dew Point')
            xlabel(app.TDAxes, 'Time [s]')
            ylabel(app.TDAxes, 'Temperature [C]')
            zlabel(app.TDAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab09GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end