classdef Lab10GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        LAB10THORLABSPHOTODIODEGUILabel  matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        WavelengthnmDropDown           matlab.ui.control.DropDown
        WavelengthnmDropDownLabel      matlab.ui.control.Label
        SamplesEditField               matlab.ui.control.NumericEditField
        SamplesEditFieldLabel          matlab.ui.control.Label
        MaxLEDCurrentmAEditField       matlab.ui.control.NumericEditField
        MaxLEDCurrentmAEditFieldLabel  matlab.ui.control.Label
        LEDResistorEditField           matlab.ui.control.NumericEditField
        LEDResistorLabel               matlab.ui.control.Label
        PhotoResistorEditField         matlab.ui.control.NumericEditField
        PhotoResistorLabel             matlab.ui.control.Label
        PhotoAxes                      matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        FileNameEditField              matlab.ui.control.EditField
        FileNameLabel                  matlab.ui.control.Label
        PhotoCurrentChDropDown         matlab.ui.control.DropDown
        PhotoCurrentChDropDownLabel    matlab.ui.control.Label
        LEDCurrentChDropDownLabel      matlab.ui.control.Label
        LEDCurrentChDropDown           matlab.ui.control.DropDown
        RampCurrentChDropDown          matlab.ui.control.DropDown
        RampCurrentChDropDownLabel     matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        ClearButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        %DAQ Variables
        d
        dev
        data

        %Channel Variables
        LEDCurrentCh
        AOChannel
        PCurrentCh
        ch0
        ch1
        ch2
        
        %Data Variables
        R_lambda                            %responsivity derived from Figure 1 in lab manual
        delta_Iout                          %step size of I out based on Imax and LED_Samples
        LED_Vout=zeros(1,2047)              %variable to be populated with outputvoltages
        scanData                            %variable to be populated with data read from DAQ
        LED_Data                            %Current values output to led
        PhotoDiode_Data                     %Current values calculated from photodiode resistor measurement
        sampleN
        LED_Power

        %UI Variables
        lamda                               %wavelength of incident light
        sampleRate                          %rate of aquisition in samples/second
        fileName = strings;                           %path for data to save to
        I_max                               %current through LED
        photo_R                             %resistor limiting sensor current
        LED_R                               %resistor limiting LED current
        LED_samples                         %resolution for anolog output generation
        i = 1                               %iterator

        %Constants
        Imax_const = 0.030                  %Variable to set max current allowed
        v_LED = 0.6                         %voltage drop across LED
        VariableNames = {'LED Current [mA]','PhotoCurrent [mA]','Incident Power [mW]'};        
        VariableUnits = {'A','A'};        
        AllDataTimeTable


    end
    
    methods (Access = private)
        % Function to populate Analog output array by passing variable LED_vout by refernece %
        function generateAO(app,I_max,LED_R,dI)
            LED_i = 0;  %start with no current
            j = 1;      %iterator
            for j=1:app.LED_samples    %repeat until max current reached
                app.LED_Vout(j) = LED_i * LED_R + 0.6;  %populate vout array with analog output values
                LED_i = LED_i + dI;         %add difference in current
            end
        end
        
        function results = PhotoCurrentFunc(app,ledPower)      %inputs the responsivity and power of resistor to calculate photocurrent
            results = ledPower*app.R_lambda;
            return
        end
        
        function PlotData(app,scan,~)
            plot(app.UIFigure,app.LED_Data,app.PhotoDiode_Data)
        end
        
        function StopCollectionFunc(app)                                                                %runs when data collection is finished
            app.LED_Data = app.scanData(:,1);
            app.PhotoDiode_Data = app.scanData(:,2);
%             app.PhotoDiode_Data = app.LED_Power * app.R_lambda;
            plot(app.PhotoAxes,app.LED_Data,app.PhotoDiode_Data);
%             app.LED_Data = app.LED_Data / app.LED_R;
%             app.LED_Power = app.scanData(:,1) .* app.LED_Data;
            app.AllDataTimeTable = table(app.LED_Data,app.PhotoDiode_Data,app.PhotoDiode_Data*.5,'VariableNames',app.VariableNames);
            writetable(app.AllDataTimeTable,app.fileName);                                           %write data to excel
            removechannel(app.d, 1:length(app.d.Channels));                                             %remove channels from daq measurements
            app.LEDCurrentChDropDown.Enable = "on";                                                     %re-enable the front panel options
            app.RampCurrentChDropDown.Enable = "on";    
            app.WavelengthnmDropDown.Enable = "on";
            app.MaxLEDCurrentmAEditField.Enable = "on";
            app.FileNameEditField.Enable = "on";
            app.StartButton.Enable = "on";
            app.PhotoCurrentChDropDown.Enable = "on";
            app.PhotoResistorEditField.Enable = "on";
            app.LEDResistorEditField.Enable = "on";
            app.SamplesEditField.Enable="on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFunc(app)
            devices = daqlist("ni");                                                                    %creats list of devides
            app.dev = devices(1,"DeviceID");                                                            %saves device name of first daq found in app.dev
            app.d = daq('ni');                                                                          %create data aquisition object, always need this line*** passes to all function that do data aquisition
            app.LEDCurrentCh = app.LEDCurrentChDropDown.Value;                                          %initialize default DAQ input Channel
            app.PCurrentCh = app.PhotoCurrentChDropDown.Value;
            app.AOChannel = app.RampCurrentChDropDown.Value;                                            %initialize default DAQ input Channel
            SamplesEditFieldValueChanged(app);
            LEDCurrentChanged(app);
            app.delta_Iout = app.I_max / app.LED_samples;                                               %initialize current step size
            app.photo_R = app.PhotoResistorEditField.Value;
            app.LED_R = app.LEDResistorEditField.Value;
            app.lamda = app.WavelengthnmDropDown.Value;
            app.fileName = app.FileNameEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.LEDCurrentChDropDown.Enable = "off";                                                     %disable the front panel options while program is running
            app.RampCurrentChDropDown.Enable = "off";    
            app.WavelengthnmDropDown.Enable = "off";
            app.MaxLEDCurrentmAEditField.Enable = "off";
            app.PhotoCurrentChDropDown.Enable = "off";
            app.FileNameEditField.Enable = "off";
            app.StartButton.Enable = "off";
            app.PhotoResistorEditField.Enable = "off";
            app.LEDResistorEditField.Enable = "off";
            app.SamplesEditField.Enable="off";
     
            generateAO(app,app.I_max,app.LED_R,app.delta_Iout);                            %populate AO voltage array by reference

            %Assign Channels Based on Front Panel
            app.ch0 = addinput(app.d,app.dev.DeviceID,app.LEDCurrentCh,'Voltage');
            app.ch1 = addinput(app.d,app.dev.DeviceID,app.PCurrentCh,'Voltage');
            app.ch2 = addoutput(app.d,app.dev.DeviceID,app.AOChannel,'Voltage');
            app.ch0.TerminalConfig='Differential';                                                      %Set to differential input
            app.ch1.TerminalConfig='Differential';
            app.i = 1;                                                                                  %reset iterator
            app.d.Rate = 1000;
            app.scanData = readwrite(app.d,app.LED_Vout',"OutputFormat","Matrix");                      %Writes analog out and reads at same time
            write(app.d,0);                                                                             %clears output
%              while app.i <= app.LED_samples
%                 app.sampleN = app.LED_Vout(1:app.i);
%                  write(app.d,app.sampleN');                                                              %Writes data to analog output
%                  pause(0.1);                                                                             %wait for circuit to catch up
%                  app.scanData = read(app.d); 
%                  app.i = app.i+1;
%              end
            StopCollectionFunc(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.PhotoAxes);
        end

        % Value changed function: MaxLEDCurrentmAEditField
        function LEDCurrentChanged(app, event)
            if app.MaxLEDCurrentmAEditField.Value >= app.Imax_const
                app.I_max = app.Imax_const;
            else
                app.I_max = app.MaxLEDCurrentmAEditField.Value;               
            end
        end

        % Callback function
        function WavelengthChanged(app, event)
            app.lamda = app.WavelengthnmDropDown.Value;
            if app.lamda == 520
                app.R_lambda = 0.3;
            elseif app.lamda == 585
                app.R_lambda = 3.2;
            elseif app.lamda == 450
                app.R_lambda = 0.2;
            elseif app.lamda == 630
                app.R_lambda = 0.4;
            end
        end

        % Value changed function: RampCurrentChDropDown
        function AOChannelChanged(app, event)
            app.AOChannel = app.RampCurrentChDropDown.Value;
        end

        % Value changed function: LEDCurrentChDropDown
        function AIChannelChanged(app, event)

             if app.LEDCurrentChDropDown.Value == app.PCurrentCh                %check if same channel is checked 2 times
                app.LEDCurrentChDropDown.Value = app.LEDCurrentCh;
             else
                 app.LEDCurrentCh = app.LEDCurrentChDropDown.Value;
             end
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.fileName = app.FileNameEditField.Value;
        end

        % Value changed function: PhotoCurrentChDropDown
        function PhotoCurrentChDropDownValueChanged(app, event)
            
            if app.PhotoCurrentChDropDown.Value == app.LEDCurrentCh
                app.PhotoCurrentChDropDown.Value = app.PCurrentCh;
            else 
                app.PCurrentCh = app.PhotoCurrentChDropDown.Value;
            end
        end

        % Value changed function: PhotoResistorEditField
        function PhotoResistorEditFieldValueChanged(app, event)
            app.photo_R = app.PhotoResistorEditField.Value;
        end

        % Value changed function: LEDResistorEditField
        function LEDResistorEditFieldValueChanged(app, event)
            app.LED_R = app.LEDResistorEditField.Value;
        end

        % Value changed function: SamplesEditField
        function SamplesEditFieldValueChanged(app, event)
            if app.SamplesEditField.Value > 2047
                app.SamplesEditField.Value = 2047;
                app.LED_samples = 2047;
            else
                app.LED_samples = app.SamplesEditField.Value;
            end
            app.delta_Iout = app.I_max / app.LED_samples;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Run ramp and collect data'};
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.RampCurrentChDropDownLabel = uilabel(app.GridLayout5);
            app.RampCurrentChDropDownLabel.Text = 'Ramp Current Ch';
            app.RampCurrentChDropDown = uidropdown(app.GridLayout5);
            app.RampCurrentChDropDown.Items = {'Analog Output 0', 'Analog Output 1'};
            app.RampCurrentChDropDown.ItemsData = {'ao0', 'ao1'};
            app.RampCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @AOChannelChanged, true);
            app.RampCurrentChDropDown.Tooltip = {'Ramp out'};
            app.RampCurrentChDropDown.Value = 'ao1';
            app.LEDCurrentChDropDown = uidropdown(app.GridLayout5);
            app.LEDCurrentChDropDown.Items = {'Differential Analog Input 0', 'Differential Analog Input 1', 'Differential Analog Input 2', 'Differential Analog Input 3', 'Differential Analog Input 4', 'Differential Analog Input 5', 'Differential Analog Input 6', 'Differential Analog Input 7'};
            app.LEDCurrentChDropDown.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.LEDCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @AIChannelChanged, true);
            app.LEDCurrentChDropDown.Tooltip = {'Channel for LED current'};
            app.LEDCurrentChDropDown.Value = 'ai0';
            app.LEDCurrentChDropDownLabel = uilabel(app.GridLayout5);
            app.LEDCurrentChDropDownLabel.Text = 'LED Current Ch';
            app.PhotoCurrentChDropDownLabel = uilabel(app.GridLayout5);
            app.PhotoCurrentChDropDownLabel.Text = 'Photo Current Ch';
            app.PhotoCurrentChDropDown = uidropdown(app.GridLayout5);
            app.PhotoCurrentChDropDown.Items = {'Differential Analog Input 0', 'Differential Analog Input 1', 'Differential Analog Input 2', 'Differential Analog Input 3', 'Differential Analog Input 4', 'Differential Analog Input 5', 'Differential Analog Input 6', 'Differential Analog Input 7'};
            app.PhotoCurrentChDropDown.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PhotoCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @PhotoCurrentChDropDownValueChanged, true);
            app.PhotoCurrentChDropDown.Tooltip = {'Channel for photocurrent'};
            app.PhotoCurrentChDropDown.Value = 'ai1';
            app.FileNameLabel = uilabel(app.GridLayout5);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.GridLayout5, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Tooltip = {'Filename for csv out'};
            app.FileNameEditField.Value = 'C:\Users\woollastoncb\Desktop\Lab10_photodiodeData.csv';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.PhotoAxes = uiaxes(app.GridLayout4);
            title(app.PhotoAxes, 'Photodiode Current vs LED Current')
            xlabel(app.PhotoAxes, 'LED Current [mA]')
            ylabel(app.PhotoAxes, 'Photodiode Current [mA]')
            zlabel(app.PhotoAxes, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.PhotoResistorLabel = uilabel(app.GridLayout6);
            app.PhotoResistorLabel.Text = 'Photo Resistor [Ω]';
            app.PhotoResistorEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PhotoResistorEditField.Limits = [0 100000];
            app.PhotoResistorEditField.ValueDisplayFormat = '%.0f';
            app.PhotoResistorEditField.ValueChangedFcn = createCallbackFcn(app, @PhotoResistorEditFieldValueChanged, true);
            app.PhotoResistorEditField.Tooltip = {'Resistance to be measured across for photodiode [ohm]'};
            app.PhotoResistorEditField.Value = 20000;
            app.LEDResistorLabel = uilabel(app.GridLayout6);
            app.LEDResistorLabel.Text = 'LED Resistor [Ω]';
            app.LEDResistorEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LEDResistorEditField.Limits = [0 Inf];
            app.LEDResistorEditField.ValueDisplayFormat = '%.0f';
            app.LEDResistorEditField.ValueChangedFcn = createCallbackFcn(app, @LEDResistorEditFieldValueChanged, true);
            app.LEDResistorEditField.Tooltip = {'LEd Resisstor [ohm]'};
            app.LEDResistorEditField.Value = 250;
            app.MaxLEDCurrentmAEditFieldLabel = uilabel(app.GridLayout6);
            app.MaxLEDCurrentmAEditFieldLabel.Text = 'Max LED Current [mA]';
            app.MaxLEDCurrentmAEditField = uieditfield(app.GridLayout6, 'numeric');
            app.MaxLEDCurrentmAEditField.Limits = [0 30];
            app.MaxLEDCurrentmAEditField.ValueChangedFcn = createCallbackFcn(app, @LEDCurrentChanged, true);
            app.MaxLEDCurrentmAEditField.Tooltip = {'Limits LED current to below 30mA'};
            app.MaxLEDCurrentmAEditField.Value = 30;
            app.SamplesEditFieldLabel = uilabel(app.GridLayout6);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SamplesEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesEditFieldValueChanged, true);
            app.SamplesEditField.Tooltip = {'sets number of samples in ramp'};
            app.SamplesEditField.Value = 2047;
            app.WavelengthnmDropDownLabel = uilabel(app.GridLayout6);
            app.WavelengthnmDropDownLabel.Text = 'Wavelength [nm]';
            app.WavelengthnmDropDown = uidropdown(app.GridLayout6);
            app.WavelengthnmDropDown.Items = {'520 [Green]', '630 [Red]', '450 [Blue]', '585 [Yellow]'};
            app.WavelengthnmDropDown.ItemsData = [520 630 450 585];
            app.WavelengthnmDropDown.Tooltip = {'Select a wavelength to set responsivity of photodiode [nm]'};
            app.WavelengthnmDropDown.Value = 520;
            app.LAB10THORLABSPHOTODIODEGUILabel = uilabel(app.GridLayout4);
            app.LAB10THORLABSPHOTODIODEGUILabel.Text = 'LAB 10: THORLABS PHOTODIODE GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab10GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUpFunc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end