classdef Lab12GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        DocumentationButton             matlab.ui.control.Button
        Lab12LightIntensitytoFrequencyLabel  matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        STOPButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SupplyCurrentmAEditField        matlab.ui.control.NumericEditField
        SupplyCurrentmAEditFieldLabel   matlab.ui.control.Label
        AquisitionParametersLabel       matlab.ui.control.Label
        DAQPropertiesLabel              matlab.ui.control.Label
        LEDWavelengthDropDown           matlab.ui.control.DropDown
        LEDWavelengthDropDownLabel      matlab.ui.control.Label
        LEDResistorValueEditField       matlab.ui.control.NumericEditField
        LEDResistorValueEditFieldLabel  matlab.ui.control.Label
        SampleDelayEditField            matlab.ui.control.NumericEditField
        SampleDelayEditFieldLabel       matlab.ui.control.Label
        CurrentStepSizeEditField        matlab.ui.control.NumericEditField
        CurrentStepSizeEditFieldLabel   matlab.ui.control.Label
        MaxLEDCurrentEditField          matlab.ui.control.NumericEditField
        MaxLEDCurrentEditFieldLabel     matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        LEDCurrentChDropDownLabel       matlab.ui.control.Label
        LEDCurrentChDropDown            matlab.ui.control.DropDown
        CounterDropDown                 matlab.ui.control.DropDown
        CounterDropDownLabel            matlab.ui.control.Label
        VoltageRampAOChDropDown         matlab.ui.control.DropDown
        VoltageRampAOChDropDownLabel    matlab.ui.control.Label
        CounterPFIInputChDropDown       matlab.ui.control.DropDown
        CounterPFIInputChDropDownLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9_2                   matlab.ui.container.GridLayout
        AquisitionStatusLabel           matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        AquisitionStatusEditField       matlab.ui.control.EditField
        AquisitionStatusEditFieldLabel  matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.NumericEditField
        IntensityEditFieldLabel         matlab.ui.control.Label
        LEDCurrentEditField             matlab.ui.control.NumericEditField
        LEDCurrentmAEditFieldLabel      matlab.ui.control.Label
        IntensityPlot                   matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %Daq variables
        d
        dev
        clkID
        clk
        ch0
        ch1
        ch2

        %Channel Variables
        counterCh               %channel for the digital counter input
        rampCh                  %channel for analog output voltage ramp
        ledCh                   %LED voltage channel to be converted to current
        counter                 %counter for freq measurement
        counterCh_i             %default ch
        rampCh_i                %default ch
        ledCh_i                 %default ch
        counter_i               %default counter

        %UI Parameters
        led_Imax = 0.003        %limits the maximum range of the current ramp
        dI    = 0.0001          %sets the change in current for each step
        dt    = .1               %sets the time between aquisitions
        led_R = 100             %sets the value of the current limiting resistor
        lamda = 520e-9          %sets the wavelength for frequency conversion
        filename

        %Program Variables
        i = 1                   %iterator
        testFlag=0              %Indcates if program in test mode
        debug=0
        viflag = 0
        vipath
        AquireFlag=0            %indicates if aquisition in progress
        led_In                  %variable for the current n
        led_Vout                %blank array to be filled with ramp values
        scanData                %variable to be used for daq read
        data                    %placeholder for test data
        resp = 2.3              %variable for responsivity based on wavelength [kHz*(uW/cm^2)]
        freq                    %variable for frequency of the counter
        led_samples             %Set to number of samples
        Intensity               %Intensity calculated from calibration curve of light snesor [uW/cm^2]
        ledData                 %aquired voltage data from led current limiting resistor
        intensityData           %Data calculated by applying manufacturer curve to frequencydata
        frequencyData           %Data aquired from intensity to frequency converter
        newDataTable
        sampleN
        Vout

        %Program Constants
        Vdrop = 2
        fDark = 170                  %variable for fDark
        VariableNames = {'Current','Intensity'}          %Names for table vairables
        VariableUnits = {'mA','mW/cm^2'}                 %Units for vairable output
        getPath
        fid
        ex
    end
    
    methods (Access = private)
        
        function generateAO(app)
            try
            app.led_In = 0;                                 %start with no current
            app.led_samples = app.led_Imax/app.dI;          %number of sampels to be written out
            Vmax = (app.led_Imax * app.led_R);              %set the maximum voltage out independant of led voltage drop
            app.led_Vout = double.empty(app.led_samples+1,0);
            dV = Vmax/app.led_samples;                      %calculate the change in voltage at each step
            Vi = app.Vdrop;                                 %set Vi to the voltage drop across the LED
            app.led_Vout(1:1) = 0;                            %
            for j=2:app.led_samples+1                         %repeat until max current reached
                app.led_Vout(j) = Vi;                       %populate vout array with analog output values
                Vi = Vi + dV;                               %add dV to next voltage
            end
            catch
                app.ex=('To calculate analog out ramp');
            end
        end
        
        function InitializeVariables(app)
            %Fills all the application varaibels based on UI and defaults
            try
            daqreset;
            app.viflag = 1;
            relPath = fileparts(mfilename("fullpath"));
            app.getPath = relPath;
            app.getPath = relPath;
            system(strcat('cd ',pwd));
            app.counter_i = app.CounterDropDown.Value;
            app.counterCh_i = app.CounterPFIInputChDropDown.Value;          %Set default channel values
            app.rampCh_i = app.VoltageRampAOChDropDown.Value;
            app.ledCh_i = app.LEDCurrentChDropDown.Value;
            app.counterCh = app.counterCh_i;                                %set channel values to default
            app.rampCh = app.rampCh_i;                                      
            app.ledCh = app.ledCh_i;
            app.counter = app.counter_i;
            app.MaxLEDCurrentEditField.Value = app.led_Imax;                %set all default user interface parameters
            app.CurrentStepSizeEditField.Value = app.dI;
            app.SampleDelayEditField.Value = app.dt;
            app.LEDResistorValueEditField.Value = app.led_R;
            devices = daqlist('ni');                                        %creats list of devides
            app.dev = devices(1,'DeviceID');                                %saves device name of first daq found in app.dev
            app.d = daq('ni');                                              %create data aquisition object, always need this line*** passes to all function that do data aquisition
            catch
                app.ex=('Failed to connect to daq device');
            end
        end
        
        function AdminMeasurement(app)
            %Runs the voltage ramp with delay for measurment between
            %points, returns the output to zero volts after finished
            app.AquisitionStatusEditField.Value='Aquisition in progress';
            app.clkID = strcat('/',app.dev.DeviceID,'/',app.counterCh);

            j=1;
            try
                if app.testFlag == 1
                    configure(app);
                    InvokeApp(app);
                else
                    app.ch1 = addinput(app.d,app.dev.DeviceID,'ctr0','Frequency');              %generate frequency in channel   
                    app.ch2 = addoutput(app.d,app.dev.DeviceID,'ao1','Voltage');                %generate ramp out channel  
                    app.ch0 = addinput(app.d,app.dev.DeviceID,app.ledCh,'Voltage');             %generate led voltage in channel
                    app.ch0.TerminalConfig='Differential';                                      %Set to differential input
                    while j <= app.led_samples+1
                        write(app.d,app.led_Vout);                                       %Writes data to analog output
                        pause(app.dt);                                              %wait for circuit to catch up
                        app.scanData = read(app.d);                                 %Read data
                        stop(app.d);
                        j = j+1;
                    end
                    write(app.d,0);            
                    app.ledData = app.scanData(:,1);
                    app.frequencyData = app.scanData(:,2);
                end
                app.AquisitionStatusEditField.Value='Aquisition complete';
                PlotData(app,app.scanData);                                         %plots the data on GUI Figure
            catch
                if app.testFlag==1
                    fclose(app.fid);
                else
                    write(app.d,0);
                    removechannel(app.d, 1:length(app.d.Channels));
                end
                 app.AquisitionStatusEditField.Value='Aquisition Error, Try Again';        %message to user notifying of aquisition error
                 configure(app);
                 cla(app.IntensityPlot);                                                    %reset plot
                 app.ex=('Failed to administer aquisition');
            end
            configure(app);
        end

        function PlotData(app,scan,~)
            try
            app.intensityData = FreqToIntensity(app);
            app.ledData=app.ledData*1000;
            plot(app.IntensityPlot,app.ledData,app.frequencyData);
            catch
                app.ex=('Failed to plot');
            end
            try
            app.newDataTable = table(app.ledData,app.intensityData);
            writetable(app.newDataTable,strcat(app.getPath,app.filename,'.csv'));
            catch
                app.ex=('Failed to write file to csv');
            end
        end
        
        function results = FreqToIntensity(app)
            try
                results = (app.frequencyData(:,1) - app.fDark)/(app.resp*1000);
            catch
                app.ex=('Failed to convert frequency to intensity');
            end

        end
        
        function plotTest(app,scan,~)
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");   
            plot(app.IntensityPlot,app.timestamps,app.data);                        
            hold("on",app.IntensityPlot);
        end
        
        function DefaultPlot(app)               %resets plot features to default
            xlabel(app.IntensityPlot,'LED Current [mA]');
            ylabel(app.IntensityPlot,'Intensity [uW/cm^2]');
            title(app.IntensityPlot,'Incident Light Intensity vs. LED Current Measured by','TAOS Light-to-Frequency Converter','FontWeight','bold');
        end
        
        function TestPlot(app)                  %sets plot features to test conditions
            xlabel(app.IntensityPlot,'Time');
            ylabel(app.IntensityPlot,'Frequency');
            title(app.IntensityPlot,'TEST','');
        end
        
        function UIHandler(app)                 %update ui basedon current program status; enable/disable features so user doesn't mess things up
            if app.AquireFlag == 1
                app.STARTButton.Enable = "off";
                app.LEDCurrentChDropDown.Editable = "off";
                app.LEDWavelengthDropDown.Editable = "off";
                app.LEDResistorValueEditField.Editable="off";
                app.SampleDelayEditField.Editable="off";
                app.CurrentStepSizeEditField.Editable="off";
                app.MaxLEDCurrentEditField.Editable="off";
                app.CounterPFIInputChDropDown.Editable='off';
                app.VoltageRampAOChDropDown.Editable='off';
            else
                app.STARTButton.Enable="on";
                app.LEDCurrentChDropDown.Editable = "on";
                app.LEDWavelengthDropDown.Editable = "on";
                app.LEDResistorValueEditField.Editable="on";
                app.SampleDelayEditField.Editable="on";
                app.CurrentStepSizeEditField.Editable="on";
                app.MaxLEDCurrentEditField.Editable="on";
                app.CounterPFIInputChDropDown.Editable='on';
                app.VoltageRampAOChDropDown.Editable='on';
            end
        end
        
        function InvokeApp(app)
            app.viflag = 1;
            relPath = fileparts(mfilename("fullpath"));
            pathStr = strcat(relPath,'\vout.txt');
            app.getPath = relPath;
            if app.debug==1
                initfile = strcat(relPath,'\build\IntensityAquisition.ini');
                if isfile(initfile)
                    app.fid = fopen(initfile,'r');
%                     fid2 = fopen(strcat(app.getPath,'\build\IntensityAquisition.txt'),'w');
                        dat = fscanf(app.fid,'%c',2000);
                        dat = split(dat,'\r');

%                     dat = fread(app.fid,inf,'*char');
%                     fprintf(fid2,sscanf(dat,'%s'));
%                     fclose(fid2);
%                     fclose(app.fid);
%                     fid2 = fopen(strcat(app.getPath,'\build\IntensityAquisition.txt'),'r');
%                     s = textscan(fid2,'%s','Delimiter','\n','Whitespace');
                    for j=1:s
                        if s(j)=="[controls]"
                            stInd=j;
                        end
                    end
                    fclose(app.fid);
                    app.fid = fopen(initfile,"w");
                    for j=1:j<stInd
                        if j==stInd-2
                            fprintf(app.fid,s(j));
                        else
                            fprintf(app.fid,strcat(s(j),'\n'));
                        end
                    end
                    fprintf(app.fid,'\n[controls]');
                    fprintf(app.fid,strcat('\nctr=',app.dev.DeviceID,"/",app.counter));
                    fprintf(app.fid,strcat('\nfpi=',app.clkID),a);
                    fprintf(app.fid,strcat('\nao=',app.dev.DeviceID,"/",app.rampCh));
                    fprintf(app.fid,strcat('\nai=',app.dev.DeviceID,"/",app.ledCh));
                    fprintf(app.fid,strcat('\nsamples',string(app.led_samples+1)));
                    for j=1:app.led_samples+1 
                        it = string(j-1);
                        val=string(app.led_vout(j));
                        fprintf(app.fid,strcat('\nrow',it,'=',val));
                    end
                    fclose(app.fid);
                else
                end
                app.vipath=strcat(relPath,'\build\IntensityAquisition.exe');
                system(sprintf('start /b %s',app.vipath));
                if isfile(strcat(relPath,'\build\writeread.txt'))
                    fopen(strcat(relPath,'\build\writeread.txt'),'r');
                    for j=1:app.led_samples
                        s = textscan(app.fid,'%s','delimeter','\n');
                        app.LEDCurrentEditField.Value=str2double(s(1));
                        app.FrequencyEditField.Value=str2double(s(2));
                        app.IntensityEditField.Value=(app.FrequencyEditField.Value-app.fDark)/(app.resp*1000);
                    end
                end
                dataPath=strcat(relPath,'\build\indensityData.txt');
                filestr = fileread(dataPath);
                filebyline = regexp(filestr, '\n', 'split');
                app.frequencyData = str2double(regexp(filebyline(1),',','split')');
                app.ledData= str2double(regexp(filebyline(1),',','split')');
            else
                strarry = [strcat(app.dev.DeviceID,"/",app.counter),app.clkID,strcat(app.dev.DeviceID,"/",app.rampCh),strcat(app.dev.DeviceID,"/",app.ledCh),app.dt*1000,pathStr];
                datatable=table([array2table(app.led_Vout),strarry]);
                writetable(array2table(app.led_Vout),pathStr);
                e=actxserver('LabVIEW.Application');
                app.vipath=strcat(relPath,'\labviewFunc.vi');
                vi=invoke(e,'GetVIReference',app.vipath);
                system(sprintf('start /b %s',app.vipath));
                %             !start /b C:\Users\woollastoncb\Documents\TransducersMATLAB\build\IntensityAquisition.exe
                vi.SetControlValue('Counter',strcat(app.dev.DeviceID,"/",app.counter));
                vi.SetControlValue('CounterPFI',app.clkID);
                vi.SetControlValue('AOChannel',strcat(app.dev.DeviceID,"/",app.rampCh));
                vi.SetControlValue('AIChannel',strcat(app.dev.DeviceID,"/",app.ledCh));
                vi.SetControlValue('Delay',app.dt*1000);
                vi.SetControlValue('Path',pathStr);
                vi.Run;
                app.frequencyData=vi.GetControlValue('fvar')*1000;
                app.ledData=vi.GetControlValue('ivar');
            end
            app.viflag = 0;
            system(sprintf('exit %s',app.vipath));
        end
        
        function configure(app)
            daqreset;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitializeVariables(app);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.AquireFlag = 1;                                             %update ui for aquisition
            UIHandler(app);
            generateAO(app);                                                %populate AO voltage array by reference
            AdminMeasurement(app);                                          %runs the measurement and data collection
%             throw(app.ex)
            app.AquireFlag = 0;
            UIHandler(app);                                                 %update ui after aquisition
        end

        % Value changed function: CounterPFIInputChDropDown
        function CounterPFIInputChDropDownValueChanged(app, event)
            app.counterCh = app.CounterPFIInputChDropDown.Value;
        end

        % Value changed function: MaxLEDCurrentEditField
        function MaxLEDCurrentEditFieldValueChanged(app, event)
            app.led_Imax = app.MaxLEDCurrentEditField.Value/1000;
        end

        % Value changed function: CurrentStepSizeEditField
        function CurrentStepSizeEditFieldValueChanged(app, event)
            app.dI = app.CurrentStepSizeEditField.Value/1000;
        end

        % Value changed function: SampleDelayEditField
        function SampleDelayEditFieldValueChanged(app, event)
            app.dt = app.SampleDelayEditField.Value;           
        end

        % Value changed function: LEDResistorValueEditField
        function LEDResistorValueEditFieldValueChanged(app, event)
            app.led_R = app.LEDResistorValueEditField.Value;
        end

        % Value changed function: LEDWavelengthDropDown
        function LEDWavelengthDropDownValueChanged(app, event)
            app.lamda = app.LEDWavelengthDropDown.Value/1000000000;
        end

        % Value changed function: VoltageRampAOChDropDown
        function VoltageRampAOChDropDownValueChanged(app, event)
            app.rampCh = app.VoltageRampAOChDropDown.Value;
        end

        % Value changed function: LEDCurrentChDropDown
        function LEDCurrentChDropDownValueChanged(app, event)
            app.ledCh = app.LEDCurrentChDropDown.Value;
        end

        % Callback function
        function TESTCheckBoxValueChanged(app, event)
            app.testFlag = app.TESTCheckBox.Value;
            cla(app.IntensityPlot);
            if app.TESTCheckBox.Value==0
                DefaultPlot(app);
            else
                TestPlot(app);
            end
        end

        % Value changed function: CounterDropDown
        function CounterDropDownValueChanged(app, event)
            app.counter = app.CounterDropDown.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.viflag==1
                system(sprintf('exit %s',app.vipath));
                app.ch2 = addoutput(app.d,app.dev.DeviceID,'ao1','Voltage');
                write(app.d,0);
                removechannel(app.d, 1:length(app.d.Channels));
            else
                
            end
        end

        % Button pushed function: DocumentationButton
        function DocumentationButtonPushed(app, event)
            system(sprintf('start %s',strcat(app.getPath,'\LAB12_README.pdf')));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {341, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.CounterPFIInputChDropDownLabel = uilabel(app.GridLayout4);
            app.CounterPFIInputChDropDownLabel.Text = 'Counter PFI Input Ch';
            app.CounterPFIInputChDropDown = uidropdown(app.GridLayout4);
            app.CounterPFIInputChDropDown.Items = {'PFI0', 'PFI1', 'PFI2', 'PFI3', 'PFI4', 'PFI5', 'PFI6', 'PFI7'};
            app.CounterPFIInputChDropDown.ItemsData = {'PFI0', 'PFI1', 'PFI2', 'PFI3', 'PFI4', 'PFI5', 'PFI6', 'PFI7'};
            app.CounterPFIInputChDropDown.ValueChangedFcn = createCallbackFcn(app, @CounterPFIInputChDropDownValueChanged, true);
            app.CounterPFIInputChDropDown.Tooltip = {'Sets the channel for the frequency counter, PFI0 is default ctr0 source configuration.'};
            app.CounterPFIInputChDropDown.Value = 'PFI0';
            app.VoltageRampAOChDropDownLabel = uilabel(app.GridLayout4);
            app.VoltageRampAOChDropDownLabel.Text = 'Voltage Ramp AO Ch';
            app.VoltageRampAOChDropDown = uidropdown(app.GridLayout4);
            app.VoltageRampAOChDropDown.Items = {'Analog Output 0', 'Analog Output 1'};
            app.VoltageRampAOChDropDown.ItemsData = {'ao0', 'ao1'};
            app.VoltageRampAOChDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageRampAOChDropDownValueChanged, true);
            app.VoltageRampAOChDropDown.Tooltip = {'Sets the channel for the analog output voltage ramp'};
            app.VoltageRampAOChDropDown.Value = 'ao1';
            app.CounterDropDownLabel = uilabel(app.GridLayout4);
            app.CounterDropDownLabel.Text = 'Counter #';
            app.CounterDropDown = uidropdown(app.GridLayout4);
            app.CounterDropDown.Items = {'Counter 0', 'Counter 1', 'Counter 2', 'Counter 3'};
            app.CounterDropDown.ItemsData = {'ctr0', 'ctr1', 'ctr2', 'ctr3'};
            app.CounterDropDown.ValueChangedFcn = createCallbackFcn(app, @CounterDropDownValueChanged, true);
            app.CounterDropDown.Tooltip = {'Determines the internal counter DAQ uses to make frequency measurement'};
            app.CounterDropDown.Value = 'ctr0';
            app.LEDCurrentChDropDown = uidropdown(app.GridLayout4);
            app.LEDCurrentChDropDown.Items = {'Differential Analog Input 0', 'Differential Analog Input 1', 'Differential Analog Input 2', 'Differential Analog Input 3', 'Differential Analog Input 4', 'Differential Analog Input 5', 'Differential Analog Input 6', 'Differential Analog Input 7'};
            app.LEDCurrentChDropDown.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.LEDCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDCurrentChDropDownValueChanged, true);
            app.LEDCurrentChDropDown.Tooltip = {'Channel for LED current'};
            app.LEDCurrentChDropDown.Value = 'ai0';
            app.LEDCurrentChDropDownLabel = uilabel(app.GridLayout4);
            app.LEDCurrentChDropDownLabel.Text = 'LED Current Ch';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MaxLEDCurrentEditFieldLabel = uilabel(app.GridLayout4);
            app.MaxLEDCurrentEditFieldLabel.Text = 'Max LED Current';
            app.MaxLEDCurrentEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MaxLEDCurrentEditField.Limits = [0 30];
            app.MaxLEDCurrentEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLEDCurrentEditFieldValueChanged, true);
            app.MaxLEDCurrentEditField.Tooltip = {'Sets the max current for the voltage ramp [mA]'};
            app.MaxLEDCurrentEditField.Value = 3;
            app.CurrentStepSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.CurrentStepSizeEditFieldLabel.Text = 'Current Step Size';
            app.CurrentStepSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CurrentStepSizeEditField.Limits = [0 Inf];
            app.CurrentStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentStepSizeEditFieldValueChanged, true);
            app.CurrentStepSizeEditField.Tooltip = {'Sets the delta I for the current ramp [mA]'};
            app.CurrentStepSizeEditField.Value = 0.1;
            app.SampleDelayEditFieldLabel = uilabel(app.GridLayout4);
            app.SampleDelayEditFieldLabel.Text = 'Sample Delay';
            app.SampleDelayEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SampleDelayEditField.Limits = [0.0001 10];
            app.SampleDelayEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDelayEditFieldValueChanged, true);
            app.SampleDelayEditField.Tooltip = {'Sets the time in seconds between consecutive analog input measurements [s]'};
            app.SampleDelayEditField.Value = 0.1;
            app.LEDResistorValueEditFieldLabel = uilabel(app.GridLayout4);
            app.LEDResistorValueEditFieldLabel.Text = 'LED Resistor Value';
            app.LEDResistorValueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LEDResistorValueEditField.ValueChangedFcn = createCallbackFcn(app, @LEDResistorValueEditFieldValueChanged, true);
            app.LEDResistorValueEditField.Tooltip = {'Change the value of the LED current limiting resistor for accurate calculation of current ramp [ohm]'};
            app.LEDResistorValueEditField.Value = 100;
            app.LEDWavelengthDropDownLabel = uilabel(app.GridLayout4);
            app.LEDWavelengthDropDownLabel.Text = 'LED Wavelength';
            app.LEDWavelengthDropDown = uidropdown(app.GridLayout4);
            app.LEDWavelengthDropDown.Items = {'520 [Green]', '630 [Red]', '450 [Blue]', '585 [Yellow]'};
            app.LEDWavelengthDropDown.ItemsData = [520 630 450 585];
            app.LEDWavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDWavelengthDropDownValueChanged, true);
            app.LEDWavelengthDropDown.Tooltip = {'Select a wavelength to set responsivity of photodiode [nm]'};
            app.LEDWavelengthDropDown.Value = 520;
            app.DAQPropertiesLabel = uilabel(app.GridLayout4);
            app.DAQPropertiesLabel.Text = 'DAQ Properties';
            app.AquisitionParametersLabel = uilabel(app.GridLayout4);
            app.AquisitionParametersLabel.Text = 'Aquisition Parameters';
            app.SupplyCurrentmAEditFieldLabel = uilabel(app.GridLayout4);
            app.SupplyCurrentmAEditFieldLabel.Text = 'Supply Current [mA]';
            app.SupplyCurrentmAEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FilenameEditFieldLabel = uilabel(app.GridLayout4);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout4, 'text');
            app.FilenameEditField.Tooltip = {'Relative filename for aquisition csv'};
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.STARTButton = uibutton(app.GridLayout7, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tooltip = {'Initiates acquisition'};
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.GridLayout7, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Tooltip = {'Stops program execution'};
            app.STOPButton.Text = 'STOP';
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.Lab12LightIntensitytoFrequencyLabel = uilabel(app.GridLayout10);
            app.Lab12LightIntensitytoFrequencyLabel.Text = {'Lab 12: Light Intensity '; 'to Frequency'};
            app.DocumentationButton = uibutton(app.GridLayout10, 'push');
            app.DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentationButtonPushed, true);
            app.DocumentationButton.Tooltip = {'Provides configuration documentation for the application'};
            app.DocumentationButton.Text = 'Documentation';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, {'Frequency vs LED Current for TAOS'; 'Light-Frequency Converter'})
            xlabel(app.UIAxes, 'LED Current [mA]')
            ylabel(app.UIAxes, 'Frequency [kHz]')
            zlabel(app.UIAxes, 'Z')
            app.IntensityPlot = uiaxes(app.GridLayout2);
            title(app.IntensityPlot, {'Incident Light Intensity vs. LED Current Measured by '; 'TAOS Light-to-Frequency Converter'})
            xlabel(app.IntensityPlot, 'LED Current [mA]')
            ylabel(app.IntensityPlot, 'Intensity [uW/cm^2]')
            zlabel(app.IntensityPlot, 'Z')
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.LEDCurrentmAEditFieldLabel = uilabel(app.GridLayout8);
            app.LEDCurrentmAEditFieldLabel.Text = 'LED Current [mA]';
            app.LEDCurrentEditField = uieditfield(app.GridLayout8, 'numeric');
            app.IntensityEditFieldLabel = uilabel(app.GridLayout8);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.GridLayout8);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.GridLayout8, 'numeric');
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.AquisitionStatusEditFieldLabel = uilabel(app.GridLayout9);
            app.AquisitionStatusEditFieldLabel.Tooltip = {'Current status of aquisition'};
            app.AquisitionStatusEditFieldLabel.Text = 'Aquisition Status';
            app.AquisitionStatusEditField = uieditfield(app.GridLayout9, 'text');
            app.AquisitionStatusEditField.Tooltip = {'Provides aquisition feedback to user'};
            app.GridLayout9_2 = uigridlayout(app.GridLayout2);
            app.AquisitionStatusLabel = uilabel(app.GridLayout9_2);
            app.AquisitionStatusLabel.Text = 'Aquisition Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab12GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end