classdef MatlabGUIpractice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StopButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn
        d
        dev
        data
        ch0
        ch1
        ch2
        inTemp_v
        inPres_v
        Vin
        AllDataTimeTable
        timestamps
    end
    
    methods (Access = private)
        function plotData(app,scan,~)   %~ is neccessary if you are not using the third input event
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");
            app.inTemp_v = app.data(:,1);
            app.inPres_v = app.data(:,1);
            app.Vin = app.data(:,1);
            app.NewDataTimeTable = timetable(datetime("now"),mean(inTemp_v),mean(inPres_v),mean(Vin));
            plot(app.UIAxes,app.timestamps,app.inTemp_v,"g");
            hold(app.UIAxes, "on");
            plot(app.UIAxes,app.timestamps,app.inTemp_v,"g");
            plot(app.UIAxes,app.timestamps,app.inTemp_v,"g");

        end
        
        function results = func2(app,Vt,Vreg)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
            app.devices = daqlist("ni");            %creats list of devides
            app.dev = devices(1,"DeviceID");        %saves device name of first daq found in app.dev
            app.d = daq('ni');                      %create data aquisition object, always need this line*** passes to all function that do data aquisition
            app.ch0 = addinput(app.d, app.dev.DeviceID,'ai0','voltage');        %Doesn't recomend hard coding the output channel, app.*channelname*.Value attatched to drop down list 
            app.ch1 = addinput(app.d, app.dev.DeviceID,'ai1','voltage');
            app.ch2 = addinput(app.d, app.dev.DeviceID,'ai2','voltage');            
            app.d.Rate = 1000;                                                  %also attatch to front panel***
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";
            app.ScansAvailibleFcnCount = app.d.Rate*1;          %only aquires every x number of samples
            app.d.ScansAvailableFcn = @(scan,evt) plotData(app,scan);   %Executes once callback is met
            cla(app.UIAxes);
            start(app.d,"continuous");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % disable unneccesary components
            app.StopButton.Enable = false;
            
            % save input data
            writetable(app.NewDataTimeTable,"Lab08data.xls")
            % stop aquisition
                              
            stop(app.d);
            removechannel(app.d, [1:length(app.d.Channels)]);
            %removeChannel(app.s,2);
            
            % enable component
            hold(app.UIAxes, 'off');
            app.StartButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabGUIpractice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end